classdef Spring_mass_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PlotGraphsButton          matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Hyperlink                 matlab.ui.control.Hyperlink
        Image                     matlab.ui.control.Image
        damping_ratio_numeric     matlab.ui.control.NumericEditField
        natural_freq_numeric      matlab.ui.control.NumericEditField
        forcing_freq_numeric      matlab.ui.control.NumericEditField
        init_veluEditField        matlab.ui.control.NumericEditField
        init_veluEditFieldLabel   matlab.ui.control.Label
        init_dispuEditField       matlab.ui.control.NumericEditField
        init_dispuEditFieldLabel  matlab.ui.control.Label
        StiffnessKEditField       matlab.ui.control.NumericEditField
        StiffnessKEditFieldLabel  matlab.ui.control.Label
        ForceP_0EditField         matlab.ui.control.NumericEditField
        ForceP_0EditFieldLabel    matlab.ui.control.Label
        TimeSlider                matlab.ui.control.RangeSlider
        TimeSliderLabel           matlab.ui.control.Label
        NaturalFreqSlider         matlab.ui.control.Slider
        NaturalFreqSliderLabel    matlab.ui.control.Label
        DampingRatioSlider        matlab.ui.control.Slider
        DampingRatioLabel         matlab.ui.control.Label
        ForcingFreqSlider         matlab.ui.control.Slider
        ForcingFreqSliderLabel    matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DisplacementFunction 
        VelocityFunction
        AccelerationFunction

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % --- ONE-TIME HEAVY CALCULATIONS ---
            app.UIFigure.Name = "Spring-Mass-Damper System Analyzer"; % Better title
            
            % 1. Define all variables symbolically
            syms zeeta wbar w wd P0 t u v K beeta

            % 2. Create the symbolic displacement expression
            disp_expr = (exp(-zeeta*w.*t)).*((u + 2*(P0/K)*zeeta*beeta/((1-beeta^2)^2 + (2*zeeta*beeta)^2)).*cos(wd.*t)+ ...
                ((P0/K)*(w/wd)*(2*zeeta^2*beeta-beeta*(1-beeta^2))/((1-beeta^2)^2+(2*zeeta*beeta)^2)+(v+u*w*zeeta)/wd).*sin(wd.*t)) + ( ...
                (P0/K)*(1/((1-beeta^2)^2 + (2*zeeta*beeta)^2))*((1-beeta^2)*sin(wbar.*t)-2*zeeta*beeta*cos(wbar.*t)));

            % 3. Differentiate with respect to 't' to get velocity
            vel_expr = diff(disp_expr, t);
            acc_expr = diff(vel_expr, t);
            

            % 4. Convert symbolic expressions to fast MATLAB function handles
            %    and store them in the app's properties.
            %    The order of variables here MUST match the order in the function call later.
            vars = [zeeta, wbar, w, wd, P0, t, u, v, K, beeta];
            app.DisplacementFunction = matlabFunction(disp_expr, 'Vars', vars);
            app.VelocityFunction     = matlabFunction(vel_expr, 'Vars', vars);
            app.AccelerationFunction     = matlabFunction(acc_expr, 'Vars', vars);
            
            % Optional: Display a message in the command window
            %disp('Startup complete: Displacement and Velocity functions are ready.');
            grid(app.UIAxes, 'on');
            grid(app.UIAxes2, 'on');
            grid(app.UIAxes3, 'on');
        end

        % Button pushed function: PlotGraphsButton
        function PlotGraphsButtonPushed(app, event)
            zeeta = app.DampingRatioSlider.Value;
            wbar = app.ForcingFreqSlider.Value;
            w = app.NaturalFreqSlider.Value;
            beeta = wbar/w;
            wd = w*(1-zeeta^2)^0.5;
            P0 = app.ForceP_0EditField.Value;
            K = app.StiffnessKEditField.Value;
            time = app.TimeSlider.Value;
            t = time(1):0.01:time(2);
            u = app.init_dispuEditField.Value;
            v = app.init_veluEditField.Value;
            u_t = app.DisplacementFunction(zeeta, wbar, w, wd, P0, t, u, v, K, beeta);
            v_t = app.VelocityFunction(zeeta, wbar, w, wd, P0, t, u, v, K, beeta);
            a_t = app.AccelerationFunction(zeeta, wbar, w, wd, P0, t, u, v, K, beeta);
            plot(app.UIAxes, t, u_t)
            grid(app.UIAxes, 'on');
            plot(app.UIAxes2, t, v_t)
            grid(app.UIAxes2, 'on');
            plot(app.UIAxes3, t, a_t)
            grid(app.UIAxes3, 'on');
        end

        % Value changed function: damping_ratio_numeric
        function damping_ratio_numericValueChanged(app, event)
            value = app.damping_ratio_numeric.Value;
            app.DampingRatioSlider.Value = value;
        end

        % Value changing function: DampingRatioSlider
        function DampingRatioSliderValueChanging(app, event)
            changingValue = event.Value;
            app.damping_ratio_numeric.Value = changingValue;
        end

        % Value changed function: forcing_freq_numeric
        function forcing_freq_numericValueChanged(app, event)
            value = app.forcing_freq_numeric.Value;
            app.ForcingFreqSlider.Value = value;
        end

        % Value changing function: ForcingFreqSlider
        function ForcingFreqSliderValueChanging(app, event)
            changingValue = event.Value;
            app.forcing_freq_numeric.Value = changingValue;
        end

        % Value changed function: natural_freq_numeric
        function natural_freq_numericValueChanged(app, event)
            value = app.natural_freq_numeric.Value;
            app.NaturalFreqSlider.Value = value;
        end

        % Value changing function: NaturalFreqSlider
        function NaturalFreqSliderValueChanging(app, event)
            changingValue = event.Value;
            app.natural_freq_numeric.Value = changingValue;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'BITS_Pilani-Logo.svg.png');
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ForcingFreqSliderLabel = uilabel(app.LeftPanel);
            app.ForcingFreqSliderLabel.Text = {'Forcing'; 'Freq.'};
            app.ForcingFreqSlider = uislider(app.LeftPanel);
            app.ForcingFreqSlider.ValueChangingFcn = createCallbackFcn(app, @ForcingFreqSliderValueChanging, true);
            app.DampingRatioLabel = uilabel(app.LeftPanel);
            app.DampingRatioLabel.Text = {'Damping'; 'Ratio'};
            app.DampingRatioSlider = uislider(app.LeftPanel);
            app.DampingRatioSlider.Limits = [0 1.2];
            app.DampingRatioSlider.ValueChangingFcn = createCallbackFcn(app, @DampingRatioSliderValueChanging, true);
            app.NaturalFreqSliderLabel = uilabel(app.LeftPanel);
            app.NaturalFreqSliderLabel.Text = {'Natural'; 'Freq.'};
            app.NaturalFreqSlider = uislider(app.LeftPanel);
            app.NaturalFreqSlider.ValueChangingFcn = createCallbackFcn(app, @NaturalFreqSliderValueChanging, true);
            app.TimeSliderLabel = uilabel(app.LeftPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.LeftPanel, 'range');
            app.ForceP_0EditFieldLabel = uilabel(app.LeftPanel);
            app.ForceP_0EditFieldLabel.Text = 'Force: P_0';
            app.ForceP_0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.StiffnessKEditFieldLabel = uilabel(app.LeftPanel);
            app.StiffnessKEditFieldLabel.Text = 'Stiffness K';
            app.StiffnessKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.init_dispuEditFieldLabel = uilabel(app.LeftPanel);
            app.init_dispuEditFieldLabel.Text = 'init_disp u';
            app.init_dispuEditField = uieditfield(app.LeftPanel, 'numeric');
            app.init_veluEditFieldLabel = uilabel(app.LeftPanel);
            app.init_veluEditFieldLabel.Text = 'init_vel u''';
            app.init_veluEditField = uieditfield(app.LeftPanel, 'numeric');
            app.forcing_freq_numeric = uieditfield(app.LeftPanel, 'numeric');
            app.forcing_freq_numeric.ValueChangedFcn = createCallbackFcn(app, @forcing_freq_numericValueChanged, true);
            app.natural_freq_numeric = uieditfield(app.LeftPanel, 'numeric');
            app.natural_freq_numeric.ValueChangedFcn = createCallbackFcn(app, @natural_freq_numericValueChanged, true);
            app.damping_ratio_numeric = uieditfield(app.LeftPanel, 'numeric');
            app.damping_ratio_numeric.ValueChangedFcn = createCallbackFcn(app, @damping_ratio_numericValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BITS_Pilani-Logo.svg.png');
            app.Hyperlink = uihyperlink(app.LeftPanel);
            app.Hyperlink.URL = 'https://www.bits-pilani.ac.in/hyderabad/';
            app.Hyperlink.Text = 'BITS Pilani, Hyderabad Campus';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Enable = 'off';
            app.Label.Text = '__________________________________';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '__________________________________';
            app.PlotGraphsButton = uibutton(app.LeftPanel, 'push');
            app.PlotGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGraphsButtonPushed, true);
            app.PlotGraphsButton.Text = 'Plot Graphs!';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Displacement Time Graph')
            ylabel(app.UIAxes, 'Displacement u(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Velocity Time Graph')
            ylabel(app.UIAxes2, 'Velocity v(t)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Acceleration Time Graph')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Acceleration a(t)')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spring_mass_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end